classdef immo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImmoAppUIFigure           matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        HTML                      matlab.ui.control.HTML
        GetDataButton             matlab.ui.control.Button
        CommuneEditField          matlab.ui.control.NumericEditField
        CommuneEditFieldLabel     matlab.ui.control.Label
        RealEstateinFranceLabel   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        StartDateDatePicker       matlab.ui.control.DatePicker
        StartDateDatePickerLabel  matlab.ui.control.Label
        MaxPriceSpinner           matlab.ui.control.Spinner
        MaxPriceSpinnerLabel      matlab.ui.control.Label
        MinPriceSpinner           matlab.ui.control.Spinner
        MinPriceSpinnerLabel      matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        MapTab                    matlab.ui.container.Tab
        HistTab                   matlab.ui.container.Tab
        UIAxes2                   matlab.ui.control.UIAxes
        Table                     matlab.ui.container.Tab
        UITable                   matlab.ui.control.Table
    end

    
    properties (Access = private)
        data % Description
        gx
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.data = load_data(app.CommuneEditField.Value)
            app.gx = geoaxes(app.MapTab);
            % geolimits(gx,[41 50],[-122.95 -110])
            % geoscatter(app.UIAxes,48.864716,2.349014)
            geolimits(app.gx,[48.8 48.9],[2.2 2.4])
            % geobasemap(gx,"streets")
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            % gx = geoaxes(app.MapTab);
            T = load_data(app.CommuneEditField.Value);
            price_min = app.MinPriceSpinner.Value;
            price_max = app.MaxPriceSpinner.Value;
            date_start = app.StartDateDatePicker.Value;
            app.data = T(T.prixm2 > price_min & T.prixm2 < price_max & T.date_mutation > date_start,{'id_mutation','date_mutation','valeur_fonciere','prixm2','adresse_numero','adresse_nom_voie','latitude','longitude','marker_color'});
            s = geoscatter(app.gx,app.data,"latitude","longitude","filled");
            s.ColorVariable = "prixm2";
            histogram(app.UIAxes2,app.data.prixm2)
            % Colum name id_mutation,date_mutation,valeur_fonciere,prixm2,adresse_numero,adresse_nom_voie,latitude,longitude,marker_color
            app.UITable.Data = app.data;
            % c = colorbar;
            % c.Label.String = "Price (€) per square meter";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImmoAppUIFigure = uifigure('Visible', 'off');
            app.ImmoAppUIFigure.Name = '🏠 Immo App ';
            app.GridLayout = uigridlayout(app.ImmoAppUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.HistTab = uitab(app.TabGroup);
            app.HistTab.Title = 'Hist';
            app.UIAxes2 = uiaxes(app.HistTab);
            title(app.UIAxes2, 'Price (€) per square meter')
            zlabel(app.UIAxes2, 'Z')
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.UITable = uitable(app.Table);
            app.UITable.ColumnName = {'id_mutation'; 'date_mutation'; 'valeur_fonciere'; 'prixm2'; 'adresse_numero'; 'adresse_nom_voie'; 'latitude'; 'longitude'; 'marker_color'};
            app.UITable.RowName = {};
            app.MinPriceSpinnerLabel = uilabel(app.GridLayout);
            app.MinPriceSpinnerLabel.Text = 'Min Price';
            app.MinPriceSpinner = uispinner(app.GridLayout);
            app.MinPriceSpinner.Step = 500;
            app.MinPriceSpinner.Limits = [1000 15000];
            app.MinPriceSpinner.ValueDisplayFormat = '%.0f';
            app.MinPriceSpinner.Value = 7500;
            app.MaxPriceSpinnerLabel = uilabel(app.GridLayout);
            app.MaxPriceSpinnerLabel.Text = 'Max Price';
            app.MaxPriceSpinner = uispinner(app.GridLayout);
            app.MaxPriceSpinner.Step = 500;
            app.MaxPriceSpinner.Limits = [1000 15000];
            app.MaxPriceSpinner.ValueDisplayFormat = '%.0f';
            app.MaxPriceSpinner.Value = 10000;
            app.StartDateDatePickerLabel = uilabel(app.GridLayout);
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.GridLayout);
            app.StartDateDatePicker.Limits = [datetime([2023 1 1]) datetime([2023 12 31])];
            app.StartDateDatePicker.Value = datetime([2023 3 14]);
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.RealEstateinFranceLabel = uilabel(app.GridLayout);
            app.RealEstateinFranceLabel.Text = '🏠 Real Estate in France🥖🥐';
            app.CommuneEditFieldLabel = uilabel(app.GridLayout);
            app.CommuneEditFieldLabel.Text = 'Commune';
            app.CommuneEditField = uieditfield(app.GridLayout, 'numeric');
            app.CommuneEditField.ValueDisplayFormat = '%.0f';
            app.CommuneEditField.Value = 75114;
            app.GetDataButton = uibutton(app.GridLayout, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.HTML = uihtml(app.GridLayout);
            app.HTML.HTMLSource = 'Source: <br/> <a href="https://www.data.gouv.fr/fr/datasets/5cc1b94a634f4165e96436c1/ "> Demandes de valeurs foncières</a>';
            % Show the figure after all components are created
            app.ImmoAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = immo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImmoAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImmoAppUIFigure)
        end
    end
end